classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        graph                  matlab.ui.control.UIAxes
        TransformationLabel    matlab.ui.control.Label
        transformation         matlab.ui.control.DropDown
        gammaSliderLabel       matlab.ui.control.Label
        gammaSlider            matlab.ui.control.Slider
        aSliderLabel           matlab.ui.control.Label
        aSlider                matlab.ui.control.Slider
        bSliderLabel           matlab.ui.control.Label
        bSlider                matlab.ui.control.Slider
        gammaValue             matlab.ui.control.TextArea
        aValue                 matlab.ui.control.TextArea
        bValue                 matlab.ui.control.TextArea
        PlotButton             matlab.ui.control.Button
        GenerateNewDataButton  matlab.ui.control.Button
        OptimizelinearButton   matlab.ui.control.Button
    end


    methods (Access = public)
    
        function replot(app)
            x_data = evalin('base','training_data');
            y_data = evalin('base','training_values');
            unknown = evalin('base','testing_data');
            trans = evalin('base','trans');
            gamma = evalin('base','gamma');
            a = evalin('base','a');
            b = evalin('base','b');
            classes = SVMLS(x_data,y_data,unknown,trans,gamma,a,b);
            
            cla(app.graph);
            app.graph.XLim = [0 5];
            app.graph.YLim = [0 5];
            hold(app.graph, 'on')
            plot(app.graph, x_data(1, 1:size(x_data,2)/2), x_data(2, 1:size(x_data,2)/2), 'r *')
            plot(app.graph, x_data(1, size(x_data,2)/2+1:size(x_data,2)), x_data(2, size(x_data,2)/2+1:size(x_data,2)), 'b *');
            for i = 1:676
                if (classes(i) == 1)
                    plot(app.graph, unknown(1,i), unknown(2, i), 'black +')
                end
            end
            fprintf("ploted\n")
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            transformationValueChanged(app);
            gammaSliderValueChanged(app);
            aSliderValueChanged(app);
            bSliderValueChanged(app);
            generate_data();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            replot(app);
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            a = app.aSlider.Value;
            assignin('base','a',a);
            %replot(app);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            changinga = event.Value;
            app.aValue.Value = num2str(changinga);
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            b = app.bSlider.Value;
            assignin('base','b',b);
            %replot(app);
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            changingb = event.Value;
            app.bValue.Value = num2str(changingb);
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged(app, event)
            gamma = app.gammaSlider.Value;
            assignin('base','gamma',gamma);
            %replot(app);
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changinggamma = event.Value;
            app.gammaValue.Value = num2str(changinggamma);
        end

        % Value changed function: transformation
        function transformationValueChanged(app, event)
            trans = app.transformation.Value;
            if strncmp('Linear',trans,6)
                assignin('base','trans',1);
            elseif strncmp('Polynomial',trans,10)
                assignin('base','trans',2);
            elseif strncmp('Exponential',trans,11)
                assignin('base','trans',3);
            elseif strncmp('Tanh',trans,4)
                assignin('base','trans',4);
            end
            %replot(app);
        end

        % Button pushed function: GenerateNewDataButton
        function GenerateButtonPushed(app, event)
            transformationValueChanged(app);
            gammaSliderValueChanged(app);
            aSliderValueChanged(app);
            bSliderValueChanged(app);
            generate_data();
        end

        % Button pushed function: OptimizelinearButton
        function OptimizelinearButtonPushed(app, event)
           gamma = fminbnd(@Correctness,1,3);
           %options = optimset('TolX', 1e-100, 'PlotFcns',@optimplotfval);
           %[gamma,fval,exitflag,output] = fminbnd(@Optimization,0,10,options)
           assignin('base', 'gamma', gamma)
           
           app.gammaValue.Value = num2str(gamma);
           app.gammaSlider.Value = gamma;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'SVM')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.TransformationLabel = uilabel(app.UIFigure);
            app.TransformationLabel.Text = 'Transformation';
            app.transformation = uidropdown(app.UIFigure);
            app.transformation.Items = {'Linear', 'Polynomial', 'Exponential', 'Tanh'};
            app.transformation.ValueChangedFcn = createCallbackFcn(app, @transformationValueChanged, true);
            app.transformation.Value = 'Exponential';
            app.gammaSliderLabel = uilabel(app.UIFigure);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0 10];
            app.gammaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged, true);
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.Value = 1;
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 10];
            app.aSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 1;
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0 5];
            app.bSlider.MajorTicks = [0 1 2 3 4 5];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bSlider.Value = 1;
            app.gammaValue = uitextarea(app.UIFigure);
            app.gammaValue.Value = {'1'};
            app.aValue = uitextarea(app.UIFigure);
            app.aValue.Value = {'1'};
            app.bValue = uitextarea(app.UIFigure);
            app.bValue.Value = {'1'};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GenerateNewDataButton = uibutton(app.UIFigure, 'push');
            app.GenerateNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateNewDataButton.Text = 'Generate New Data';
            app.OptimizelinearButton = uibutton(app.UIFigure, 'push');
            app.OptimizelinearButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizelinearButtonPushed, true);
            app.OptimizelinearButton.Text = 'Optimize (linear)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end